classdef bodeplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportDataPanel               matlab.ui.container.Panel
        WorkspaceLabel                matlab.ui.control.Label
        ExportdataButton              matlab.ui.control.Button
        AppendtoDropDown              matlab.ui.control.DropDown
        AppendtoDropDownLabel         matlab.ui.control.Label
        PrintfigureButton             matlab.ui.control.Button
        ConnectivityPanel             matlab.ui.container.Panel
        DG1000ZPanel                  matlab.ui.container.Panel
        IPadressEditField_2           matlab.ui.control.EditField
        IPadressEditField_2Label      matlab.ui.control.Label
        InterfaceDropDown_2Label      matlab.ui.control.Label
        InterfaceDropDown_2           matlab.ui.control.DropDown
        DS1000ZPanel                  matlab.ui.container.Panel
        IPadressEditField             matlab.ui.control.EditField
        IPadressEditFieldLabel        matlab.ui.control.Label
        InterfaceDropDown             matlab.ui.control.DropDown
        InterfaceDropDownLabel        matlab.ui.control.Label
        PlotSettingsPanel             matlab.ui.container.Panel
        XaxisDropDown                 matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        NormalizeyaxisCheckBox        matlab.ui.control.CheckBox
        PlottypexyDropDown            matlab.ui.control.DropDown
        PlottypexyLabel               matlab.ui.control.Label
        YaxisDropDown                 matlab.ui.control.DropDown
        YaxisDropDownLabel            matlab.ui.control.Label
        AquisitionSettingsPanel       matlab.ui.container.Panel
        OutputImpedanceDropDown       matlab.ui.control.DropDown
        OutputImpedanceDropDownLabel  matlab.ui.control.Label
        VoffEditField                 matlab.ui.control.NumericEditField
        VoffEditFieldLabel            matlab.ui.control.Label
        VppEditField                  matlab.ui.control.NumericEditField
        VppEditFieldLabel             matlab.ui.control.Label
        SampledistributionDropDown    matlab.ui.control.DropDown
        SampledistributionDropDownLabel  matlab.ui.control.Label
        Ch1AttenuationDropDown        matlab.ui.control.DropDown
        Ch1AttenuationDropDownLabel   matlab.ui.control.Label
        Ch2AttenuationDropDown        matlab.ui.control.DropDown
        Ch2AttenuationDropDownLabel   matlab.ui.control.Label
        FastautoscalingCheckBox       matlab.ui.control.CheckBox
        SamplesEditField              matlab.ui.control.NumericEditField
        SamplesEditFieldLabel         matlab.ui.control.Label
        StopfrequencyEditField        matlab.ui.control.NumericEditField
        StopfrequencyEditFieldLabel   matlab.ui.control.Label
        StartfrequencyEditField       matlab.ui.control.NumericEditField
        StartfrequencyEditFieldLabel  matlab.ui.control.Label
        LockfrontpanelsCheckBox       matlab.ui.control.CheckBox
        MbandwidthlimitCheckBox       matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Flags
        stopMeas = false;       % flag to abort measurment script
        dataAvailable = false;  % flag data available

        % Progress Bar
        pT                      % progress bar Percent Text
        pP                      % progress bar Patch
        pA                      % progress bar axis

        % Settings
        samples = 20;
        vpp = 1;
        voff = 0;
        fstart
        fstop

        % Measuremt data
        vpp1
        vpp2
        mag
        magdB
        attdB
        omega
        freq
        pha
        rawPha
    end
    
    methods (Access = private)
        function updateProgressBar(app, k)
                if(k == 0) 
                    title(app.pA, 'Progress');
                elseif(k == app.samples)
                    title(app.pA, 'Completed');
                end
                % update patch size and percentage text
                app.pP.XData = [0 k/app.samples k/app.samples 0]; 
                app.pT.String = sprintf('%.0f%%',round(k/app.samples*100)); 
                drawnow %update graphics
        end
        
        function setupProgressBar(app)
            fig=app;
                fig.Panel.AutoResizeChildren = 'off'; % To avoid using the whole uifigure space, advisable to place under the panel
                app.pA = subplot(1,1,1,'Parent',fig.Panel);
                
                app.pA.Position=[.1 .4 .8 .05];
                app.pA.Box='on';
                app.pA.XTick=[];
                app.pA.YTick=[];
                app.pA.Color=[0.9375 0.9375 0.9375];
                app.pA.XAxis.Limits=[0,1];
                app.pA.YAxis.Limits=[0,1];
                axtoolbar(app.pA);
                disableDefaultInteractivity(app.pA);
                title(app.pA,'Progress')

            % Create empty patch that will be updated
            app.pP = patch(app.pA,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            % Create the percent-complete text that will be updated
            app.pT = text(app.pA,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 
        end
        
        % enable/disable aquisition settings
        function setInteractivity(app, state)
            app.SamplesEditField.Enable = state;
            app.StartfrequencyEditField.Enable = state;
            app.StopfrequencyEditField.Enable = state;
            app.Ch1AttenuationDropDown.Enable = state;
            app.Ch2AttenuationDropDown.Enable = state;
            app.MbandwidthlimitCheckBox.Enable = state;
            app.LockfrontpanelsCheckBox.Enable = state;
            app.SampledistributionDropDown.Enable = state;
            app.FastautoscalingCheckBox.Enable = state;
            app.VppEditField.Enable = state;
            app.VoffEditField.Enable = state;
            app.OutputImpedanceDropDown.Enable = state;
        end
        
        % Code to be executed after finishing measurements
        function cleanup(app, DS1000Z, DG1000Z)
            fprintf(DG1000Z, ':OUTP1 OFF' );
            % unlock frontpanels
            fprintf(DS1000Z, ':SYST:LOCK OFF'); 
            fprintf(DG1000Z, ':SYSTEM:KLOCK ALL OFF'); 

            fclose(DG1000Z);
            fclose(DS1000Z);
            delete(DG1000Z);
            delete(DS1000Z);
            app.setInteractivity(true);
        end
        
        % Processing of aquired measurements
        function processData(app)
            app.mag = app.vpp2./app.vpp1;
            app.magdB = 20.*log10(app.mag);
            app.attdB = -app.magdB;
            app.omega = 2*pi.*app.freq;
            phase = app.rawPha;
            phase(phase > 1000) = NaN;     % catch failed measurements
            app.pha = phase./180.*pi;

            app.ExportdataButton.Enable = true;
            app.dataAvailable = true;
        end
        
        % Label axis, set title
        function labelAxis(app, magAx, phaAx)
            if(app.dataAvailable)
                plotData(app, magAx, phaAx);
            end

            % X-Axis label [frequency (1)/ angular frequency (2)]
            xlabel(magAx, getXLabel(app), 'Interpreter', 'latex')
            xlabel(phaAx, getXLabel(app), 'Interpreter', 'latex')

            % Y-axis label [magnitude (1)/ attenuation (2)]
            if (app.YaxisDropDown.Value == 1) 
                title(magAx, 'Magnitude')
                ylabel(magAx, getYLabel(app), 'Interpreter', 'latex')
            else 
                title(magAx, 'Attenuation')
                if (app.XaxisDropDown.Value == 1)
                    ylabel(magAx, '$a(f)$ [dB]', 'Interpreter', 'latex')
                else
                    ylabel(magAx, '$a(\omega)$ [dB]', 'Interpreter', 'latex')
                end
            end
            
            % Y-axis label phase
            title(phaAx, 'Phase')
            ylabel(phaAx, '$\varphi$ [rad]', 'Interpreter', 'latex')
            yticks(phaAx, [-pi -pi/2 0 pi/2 pi ])
            yticklabels(phaAx, {'-\pi','-\pi/2','0','-\pi/2','\pi'})
            ylim(phaAx, [-pi pi])
        end
        
        % Helper function that returns label string for x-axis
        function label = getXLabel(app)
            if (app.XaxisDropDown.Value == 1) 
                label = '$f$ [Hz]';
            else
                label = '$\omega$ [rad/s]';
            end
        end
        
        % Helper function that returns label string for y-axis
        function label = getYLabel(app)
            if (app.XaxisDropDown.Value == 1) % fct of freq or omega
                if (app.PlottypexyDropDown.Value == 1 || app.PlottypexyDropDown.Value == 3) % linear y-axis
                    label = '$|H(f)|$';
                else
                    label = '$|H(f)|$ [dB]';
                end
            else
                if (app.PlottypexyDropDown.Value == 1 || app.PlottypexyDropDown.Value == 3) % linear y-axis
                    label = '$|H(\omega)|$';
                else
                    label = '$|H(\omega)|$ [dB]';
                end
            end
        end
        
        function plotData(app, magAx, phaAx)
            plot(phaAx, getXAxis(app), app.pha);

            switch app.PlottypexyDropDown.Value
                case 1 % linear/linear
                    plot(magAx, getXAxis(app), getYAxis(app, "linear"));
                    set(magAx, 'XScale', 'linear');
                    set(phaAx, 'XScale', 'linear');
                case 2 % linear/log
                    plot(magAx, getXAxis(app), getYAxis(app, "log"));
                    set(magAx, 'XScale', 'linear');
                    set(phaAx, 'XScale', 'linear');
                case 3 % log/linear
                    plot(magAx, getXAxis(app), getYAxis(app, "linear"));
                    set(magAx, 'XScale', 'log');
                    set(phaAx, 'XScale', 'log');
                case 4  % log/log
                    plot(magAx, getXAxis(app), getYAxis(app, "log"));
                    set(magAx, 'XScale', 'log');
                    set(phaAx, 'XScale', 'log');
                otherwise
                    error('plottype switchcase undefined')
            end
            %refresh;
        end

        function axis = getYAxis(app, type)
            if (app.YaxisDropDown.Value == 1)   % magnitude
                if (type == "linear")
                    axis = app.mag;
                else
                    axis = app.magdB;
                end
            else                                % attenuation
                axis = app.attdB;
            end
        end

        function axis = getXAxis(app)
            if (app.XaxisDropDown.Value == 1)   % frequency
                axis = app.freq;
            else                                % angular freq.
                axis = app.omega;
            end
        end 
        
        function exportData(app)
            labels = {  'Ch1Vpp',...
                        'Ch2Vpp',...
                        'Magnitude',...
                        'Magnitude [dB]',...
                        'Attenuation',...
                        'Phase',...
                        'Raw phase data',...
                        'Frequency',...
                        'Angular frequency'};

            vars = {    'ch1Vpp',...
                        'ch2Vpp',...
                        'mag',...
                        'magdB',...
                        'att',...
                        'phase',...
                        'rawPhase',...
                        'freq',...
                        'angFreq'};

            vals = {    app.vpp1,...
                        app.vpp2,...
                        app.mag,...
                        app.magdB,...
                        app.attdB,...
                        app.pha,...
                        app.rawPha,...
                        app.freq,...
                        app.omega};
            title = "Export data to workspace";
            defs = logical([0 0 0 0 0 0 0 0 0]);
            export2wsdlg(labels, vars, vals,title,defs)
        end
    end

    methods (Static)
        % Calculates and rounds the vertical scale so that the curve
        % occupies half of the screen, used for fast auto-scaling
        function scale = calcScale(Vpp)
            % *2 because half of the screen, /8 because the screen fits 8 divisions
            scale = Vpp*0.25;   
            if scale < 0.001
                scale = 0.001;
            elseif scale >= 0.001 || scale < 1 
                scale = round(scale, 3);
            elseif scale >= 1 || scale < 10 
                scale = round(scale, 3);
            elseif scale >= 10
                scale = 10;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.samples = app.SamplesEditField.Value;
            app.fstart = app.StartfrequencyEditField.Value;
            app.fstop = app.StopfrequencyEditField.Value;
            labelAxis(app, app.UIAxes, app.UIAxes2);
            setupProgressBar(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.setInteractivity(false);
            app.stopMeas = false;
            app.updateProgressBar(0);
            
            ch1Vpp = zeros(1, app.samples);
            ch2Vpp = zeros(1, app.samples);
            phase = zeros(1, app.samples);
            
            % Sample distribution
            switch app.SampledistributionDropDown.Value
                case 1
                    app.freq = logspace(log10(app.fstart), log10(app.fstop), app.samples);
                    %app.SamplesEditField.Value = 1;
                case 2
                    app.freq = linspace(app.fstart, app.fstop, app.samples);
                    %app.SamplesEditField.Value = 2;
                otherwise
                    %app.SamplesEditField.Value = 3;
                    error('sample spacing not defined')    
            end
            
            
            %% Interface object
            
            % Find VISA-USB objects.
            DS1000Z = instrfind('Type', 'visa-tcpip', 'RsrcName', append('TCPIP0::', app.IPadressEditField.Value, '::inst0::INSTR'), 'Tag', '');
            DG1000Z = instrfind('Type', 'visa-tcpip', 'RsrcName', append('TCPIP0::', app.IPadressEditField_2.Value, '::inst0::INSTR'), 'Tag', '');
            
            % Create the VISA-TCPIP object if it does not exist
            % otherwise use the object that was found.
            if isempty(DS1000Z)
                DS1000Z = visa('NI', append('TCPIP0::', app.IPadressEditField.Value, '::inst0::INSTR'));
            else
                fclose(DS1000Z);
                DS1000Z = DS1000Z(1);
            end
            
            if isempty(DG1000Z)
                DG1000Z = visa('NI', append('TCPIP0::', app.IPadressEditField_2.Value, '::inst0::INSTR'));
            else
                fclose(DG1000Z);
                DG1000Z = DG1000Z(1);
            end
            
            %% Settings
            
            % Set the device property, the length of the input buffer is set to 2048.
            DS1000Z.InputBufferSize = 2048;
            
            fopen(DG1000Z);
            fopen(DS1000Z);

            % lock frontpanels
            if(app.LockfrontpanelsCheckBox.Value)
                fprintf(DG1000Z, ':SYSTEM:KLOCK ALL ON' );
                fprintf(DS1000Z, ':SYST:LOCK ON' );
            else
                fprintf(DG1000Z, ':SYSTEM:KLOCK ALL OFF' );
                fprintf(DS1000Z, ':SYST:LOCK OFF' );
            end

            %% Function Generator
            
            % set CH1 output impedance to inf
            if isequal(app.OutputImpedanceDropDown.Value, 'HighZ')
                fprintf(DG1000Z, ':OUTP1:IMP INF' );    
            else
                fprintf(DG1000Z, append(':OUTP1:IMP ', app.OutputImpedanceDropDown.Value));
            end

            %% Scope

            % TRIGGER
            % source
            % trigger coupling
            % trigger type

            % CHANNEL

            fprintf(DS1000Z, ':CHANnel1:COUPling DC' );
            fprintf(DS1000Z, ':CHANnel2:COUPling DC' );
            
            fprintf(DS1000Z, append(':CHANnel1:PROBe ', int2str(app.Ch1AttenuationDropDown.Value)));
            fprintf(DS1000Z, append(':CHANnel2:PROBe ', int2str(app.Ch2AttenuationDropDown.Value)));
            
            % enable/disable 20 MHz bandwidthlimit
            if(app.MbandwidthlimitCheckBox.Value)
                fprintf(DS1000Z, ':CHANnel1:BWLimit 20M' ); 
                fprintf(DS1000Z, ':CHANnel2:BWLimit 20M' ); 
            else
                fprintf(DS1000Z, ':CHANnel1:BWLimit OFF' ); 
                fprintf(DS1000Z, ':CHANnel2:BWLimit OFF' ); 
            end

            fprintf(DS1000Z, ':CHANnel1:DISPlay ON' );
            fprintf(DS1000Z, ':CHANnel2:DISPlay ON' );
            
            % MEASURE
            
            fprintf(DS1000Z, ':MEASure:CLEar ALL' );
            fprintf(DS1000Z, ':MEASure:SETup:PSA CHANnel1' );   % Set source A of phase measurement to CH1
            fprintf(DS1000Z, ':MEASure:SETup:PSB CHANnel2' );   % Set source B of phase measurement to CH2
            
            %% Frequency Sweep
            
            for k = 1:app.samples

                if(app.stopMeas)
                    app.stopMeas = false;
                    cleanup(app, DS1000Z, DG1000Z);
                    title(app.pA, 'Aborted');
                    return
                end
    
                pause(0.1);
                fprintf(DG1000Z, ':OUTP1 OFF' );
                % set CH1 wafeform to sinusoidal with the specified frequency, amplitude, offset 
                fprintf(DG1000Z, append(':SOUR1:APPL:SIN ', int2str(app.freq(k)), ',', int2str(app.vpp), ',', int2str(app.voff), ',0'));
                pause(0.1); % pause needed, otherwise new setting would not affect output signal
                fprintf(DG1000Z, ':OUTP1 ON' );
    
                if (app.FastautoscalingCheckBox.Value)
                    if (k == 1)
                        fprintf(DS1000Z, ':AUToscale' );
                        pause(9);                                   % auto-scale takes about 9 sec
                        fprintf(DS1000Z, ':CHANnel1:OFFSet 0' );
                        fprintf(DS1000Z, ':CHANnel2:OFFSet 0' );

                        fprintf(DS1000Z, ':MEASure:ITEM? VPP,CHANnel1' );
                        ch1Vpp(k) = str2double(fscanf(DS1000Z, '%s' ));
                        fprintf(DS1000Z, append(':CHAN1:SCAL ', sprintf('%0.7e', app.calcScale(ch1Vpp(k)))));
                    end
                    period = 1/app.freq(k);
                    nP = 2; % number of periods on the screen
                    timescale = round(period/12*nP, 9);
                    fprintf(DS1000Z, append(':TIM:MAIN:SCAL ', sprintf('%0.7e', timescale)));
                    
                    pause(0.1)

                    fprintf(DS1000Z, ':MEASure:ITEM? VPP,CHANnel1' );
                    ch1Vpp(k) = str2double(fscanf(DS1000Z, '%s' ));
                    fprintf(DS1000Z, ':MEASure:ITEM? VPP,CHANnel2' );
                    ch2Vpp(k) = str2double(fscanf(DS1000Z, '%s' ));
                    
                    fprintf(DS1000Z, append(':CHAN2:SCAL ', sprintf('%0.7e', app.calcScale(ch2Vpp(k)))));
                    pause(1.5)
                else
                    fprintf(DS1000Z, ':AUToscale' );
                    pause(9);  % auto-scale takes about 8.6 sec
                    fprintf(DS1000Z, ':MEASure:ITEM? VPP,CHANnel1' );
                    ch1Vpp(k) = str2double(fscanf(DS1000Z, '%s' ));
                    fprintf(DS1000Z, ':MEASure:ITEM? VPP,CHANnel2' );
                    ch2Vpp(k) = str2double(fscanf(DS1000Z, '%s' ));
                end
                fprintf(DS1000Z, ':MEASure:ITEM? RPHase' );
                phase(k) = str2double(fscanf(DS1000Z, '%s' ));
                updateProgressBar(app,k);
            end
            
            app.vpp1 = ch1Vpp;
            app.vpp2 = ch2Vpp;
            app.rawPha = phase;

            cleanup(app, DS1000Z, DG1000Z);
            processData(app);
            plotData(app, app.UIAxes, app.UIAxes2);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopMeas = true;
            title(app.pA, 'Aborting...')
        end

        % Value changed function: SamplesEditField
        function SamplesEditFieldValueChanged(app, event)
            app.samples = app.SamplesEditField.Value;
            
        end

        % Value changed function: StartfrequencyEditField
        function StartfrequencyEditFieldValueChanged(app, event)
            app.fstart = app.StartfrequencyEditField.Value;
            
        end

        % Value changed function: StopfrequencyEditField
        function StopfrequencyEditFieldValueChanged(app, event)
            app.fstop = app.StopfrequencyEditField.Value;
            
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            labelAxis(app, app.UIAxes, app.UIAxes2);
            
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            labelAxis(app, app.UIAxes, app.UIAxes2);
            
        end

        % Value changed function: PlottypexyDropDown
        function PlottypexyDropDownValueChanged(app, event)
            labelAxis(app, app.UIAxes, app.UIAxes2);
            
        end

        % Button pushed function: PrintfigureButton
        function PrintfigureButtonPushed(app, event)
            if (app.AppendtoDropDown.Value == 0)
                figure("Name","Measured Data")
                mAx = subplot(2,1,1);
                pAx = subplot(2,1,2);
            else
                figure(app.AppendtoDropDown.Value);
                ax = findobj( get(gcf,'Children'), '-depth', 1, 'type', 'axes');
                pAx = ax(1);
                mAx = ax(2);
                hold([mAx pAx],'on')
            end
            labelAxis(app, mAx, pAx);
            hold([mAx pAx],'off')
        end

        % Drop down opening function: AppendtoDropDown
        function AppendtoDropDownOpening(app, event)
            figArray = findobj(groot, 'type', 'figure');
            nax = arrayfun(@(F)length(findobj(F, 'type', 'axes')), figArray);
            figArray = figArray(nax == 2);
            len = length(figArray);
            items = strings(1,len+1);
            items(1) = "New figure";
            itemsData = zeros(1,len+1);
            if (len >= 0) 
                for k = 1:len
                    items(k+1) = append("Figure ", int2str(get(figArray(k), 'Number')));
                    itemsData(k+1) = get(figArray(k), 'Number');
                end
            end
            app.AppendtoDropDown.Items = items;
            app.AppendtoDropDown.ItemsData = itemsData;
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            exportData(app);
        end

        % Value changed function: OutputImpedanceDropDown
        function OutputImpedanceDropDownValueChanged(app, event)
            value = app.OutputImpedanceDropDown.Value;
            dValue = str2double(value);
            if isequal(value,'HighZ') || isequal(value,'50 Ohm')
                return
            elseif dValue >= 1 && dValue <= 10000 && mod(dValue,1) == 0
                return
            end
            uialert(app.UIFigure, 'Value must be an integer between 1 Ohm and 10 kOhm or HighZ', 'Invalid value', 'Icon', 'info')
            app.OutputImpedanceDropDown.Value = 'HighZ';
        end

        % Value changed function: VppEditField
        function VppEditFieldValueChanged(app, event)
            app.vpp = app.VppEditField.Value;
            
        end

        % Value changed function: VoffEditField
        function VoffEditFieldValueChanged(app, event)
            app.voff = app.VoffEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnitude')
            xlabel(app.UIAxes, 'f [Hz]')
            ylabel(app.UIAxes, '|H(f)| [dB]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Phase')
            xlabel(app.UIAxes2, 'f [Hz]')
            ylabel(app.UIAxes2, 'Phase [rad/pi]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-1 1];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Panel = uipanel(app.UIFigure);
            app.AquisitionSettingsPanel = uipanel(app.UIFigure);
            app.AquisitionSettingsPanel.Title = 'Aquisition Settings';
            app.MbandwidthlimitCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.MbandwidthlimitCheckBox.Text = '20M bandwidth limit';
            app.LockfrontpanelsCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.LockfrontpanelsCheckBox.Text = 'Lock frontpanels';
            app.StartfrequencyEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.StartfrequencyEditFieldLabel.Text = 'Start frequency';
            app.StartfrequencyEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.StartfrequencyEditField.UpperLimitInclusive = 'off';
            app.StartfrequencyEditField.Limits = [10 Inf];
            app.StartfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StartfrequencyEditFieldValueChanged, true);
            app.StartfrequencyEditField.Tooltip = {'Start frequency must be > 43 Hz due to auto-scaling limitiations of the Oscilloscope'};
            app.StartfrequencyEditField.Value = 50;
            app.StopfrequencyEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.StopfrequencyEditFieldLabel.Text = 'Stop frequency';
            app.StopfrequencyEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.StopfrequencyEditField.UpperLimitInclusive = 'off';
            app.StopfrequencyEditField.Limits = [43 Inf];
            app.StopfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StopfrequencyEditFieldValueChanged, true);
            app.StopfrequencyEditField.Value = 5000;
            app.SamplesEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.SamplesEditField.Limits = [1 Inf];
            app.SamplesEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesEditFieldValueChanged, true);
            app.SamplesEditField.Value = 20;
            app.FastautoscalingCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.FastautoscalingCheckBox.Text = 'Fast auto-scaling';
            app.FastautoscalingCheckBox.Value = true;
            app.Ch2AttenuationDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.Ch2AttenuationDropDownLabel.Text = 'Ch 2 Attenuation';
            app.Ch2AttenuationDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.Ch2AttenuationDropDown.Items = {'1x', '10x'};
            app.Ch2AttenuationDropDown.ItemsData = [1 10];
            app.Ch2AttenuationDropDown.Value = 1;
            app.Ch1AttenuationDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.Ch1AttenuationDropDownLabel.Text = 'Ch 1 Attenuation';
            app.Ch1AttenuationDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.Ch1AttenuationDropDown.Items = {'1x', '10x'};
            app.Ch1AttenuationDropDown.ItemsData = [1 10];
            app.Ch1AttenuationDropDown.Value = 1;
            app.SampledistributionDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.SampledistributionDropDownLabel.Text = 'Sample distribution';
            app.SampledistributionDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.SampledistributionDropDown.Items = {'log', 'linear'};
            app.SampledistributionDropDown.ItemsData = [1 2];
            app.SampledistributionDropDown.Value = 1;
            app.VppEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.VppEditFieldLabel.Text = 'Vpp';
            app.VppEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.VppEditField.Limits = [0.002 20];
            app.VppEditField.ValueChangedFcn = createCallbackFcn(app, @VppEditFieldValueChanged, true);
            app.VppEditField.Tooltip = {'2 mV to 20 V, maxium value depends on frequency and output impedance setting'};
            app.VppEditField.Value = 1;
            app.VoffEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.VoffEditFieldLabel.Text = 'Voff';
            app.VoffEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.VoffEditField.Limits = [-7.5 7.5];
            app.VoffEditField.ValueChangedFcn = createCallbackFcn(app, @VoffEditFieldValueChanged, true);
            app.VoffEditField.Tooltip = {'-7.5 V to 7.5 V'};
            app.OutputImpedanceDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.OutputImpedanceDropDownLabel.Text = 'Output Impedance';
            app.OutputImpedanceDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.OutputImpedanceDropDown.Items = {'HighZ', '50 Ohm'};
            app.OutputImpedanceDropDown.ItemsData = {'HighZ', '50'};
            app.OutputImpedanceDropDown.Editable = 'on';
            app.OutputImpedanceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputImpedanceDropDownValueChanged, true);
            app.OutputImpedanceDropDown.Tooltip = {'1 to 10 kOhm, or HighZ'};
            app.OutputImpedanceDropDown.Value = 'HighZ';
            app.PlotSettingsPanel = uipanel(app.UIFigure);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.YaxisDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.PlotSettingsPanel);
            app.YaxisDropDown.Items = {'magnitude', 'attenuation'};
            app.YaxisDropDown.ItemsData = [1 2];
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 1;
            app.PlottypexyLabel = uilabel(app.PlotSettingsPanel);
            app.PlottypexyLabel.Text = 'Plottype (x/y)';
            app.PlottypexyDropDown = uidropdown(app.PlotSettingsPanel);
            app.PlottypexyDropDown.Items = {'linear/linear', 'linear/log', 'log/linear', 'log/log'};
            app.PlottypexyDropDown.ItemsData = [1 2 3 4];
            app.PlottypexyDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypexyDropDownValueChanged, true);
            app.PlottypexyDropDown.Value = 4;
            app.NormalizeyaxisCheckBox = uicheckbox(app.PlotSettingsPanel);
            app.NormalizeyaxisCheckBox.Enable = 'off';
            app.NormalizeyaxisCheckBox.Text = 'Normalize y-axis';
            app.XaxisDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.PlotSettingsPanel);
            app.XaxisDropDown.Items = {'frequency', 'angular frequency'};
            app.XaxisDropDown.ItemsData = [1 2];
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 1;
            app.ConnectivityPanel = uipanel(app.UIFigure);
            app.ConnectivityPanel.Title = 'Connectivity';
            app.DS1000ZPanel = uipanel(app.ConnectivityPanel);
            app.DS1000ZPanel.Title = 'DS1000Z';
            app.InterfaceDropDownLabel = uilabel(app.DS1000ZPanel);
            app.InterfaceDropDownLabel.Enable = 'off';
            app.InterfaceDropDownLabel.Text = 'Interface';
            app.InterfaceDropDown = uidropdown(app.DS1000ZPanel);
            app.InterfaceDropDown.Items = {'LAN', 'USB'};
            app.InterfaceDropDown.Enable = 'off';
            app.InterfaceDropDown.Value = 'LAN';
            app.IPadressEditFieldLabel = uilabel(app.DS1000ZPanel);
            app.IPadressEditFieldLabel.Text = 'IP-adress';
            app.IPadressEditField = uieditfield(app.DS1000ZPanel, 'text');
            app.IPadressEditField.Value = '192.168.0.151';
            app.DG1000ZPanel = uipanel(app.ConnectivityPanel);
            app.DG1000ZPanel.Title = 'DG1000Z';
            app.InterfaceDropDown_2 = uidropdown(app.DG1000ZPanel);
            app.InterfaceDropDown_2.Items = {'LAN', 'USB'};
            app.InterfaceDropDown_2.Enable = 'off';
            app.InterfaceDropDown_2.Value = 'LAN';
            app.InterfaceDropDown_2Label = uilabel(app.DG1000ZPanel);
            app.InterfaceDropDown_2Label.Enable = 'off';
            app.InterfaceDropDown_2Label.Text = 'Interface';
            app.IPadressEditField_2Label = uilabel(app.DG1000ZPanel);
            app.IPadressEditField_2Label.Text = 'IP-adress';
            app.IPadressEditField_2 = uieditfield(app.DG1000ZPanel, 'text');
            app.IPadressEditField_2.Value = '192.168.0.163';
            app.ExportDataPanel = uipanel(app.UIFigure);
            app.ExportDataPanel.Title = 'Export Data';
            app.PrintfigureButton = uibutton(app.ExportDataPanel, 'push');
            app.PrintfigureButton.ButtonPushedFcn = createCallbackFcn(app, @PrintfigureButtonPushed, true);
            app.PrintfigureButton.Text = 'Print figure';
            app.AppendtoDropDownLabel = uilabel(app.ExportDataPanel);
            app.AppendtoDropDownLabel.Text = 'Append to';
            app.AppendtoDropDown = uidropdown(app.ExportDataPanel);
            app.AppendtoDropDown.Items = {'new figure'};
            app.AppendtoDropDown.ItemsData = 0;
            app.AppendtoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AppendtoDropDownOpening, true);
            app.AppendtoDropDown.Value = 0;
            app.ExportdataButton = uibutton(app.ExportDataPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Enable = 'off';
            app.ExportdataButton.Text = 'Export data';
            app.WorkspaceLabel = uilabel(app.ExportDataPanel);
            app.WorkspaceLabel.Text = 'Workspace:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bodeplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end